classdef VolumePointPicker < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure
        FileMenu               matlab.ui.container.Menu
        OpenImageMenu          matlab.ui.container.Menu
        OpenRecentMenu         matlab.ui.container.Menu
        ExportPointsMenu       matlab.ui.container.Menu
        LoadSessionMenu        matlab.ui.container.Menu
        LoadRecentSessionMenu  matlab.ui.container.Menu
        SaveSessionMenu        matlab.ui.container.Menu
        CloseAppMenu           matlab.ui.container.Menu
        ViewSettingsPanel      matlab.ui.container.Panel
        AutoMaxIntensityProjectionLabel  matlab.ui.control.Label
        MIPSwitch              matlab.ui.control.Switch
        ColormapDropDownLabel  matlab.ui.control.Label
        ColormapDropDown       matlab.ui.control.DropDown
        DVolumeSwitchLabel     matlab.ui.control.Label
        VolumeViewSwitch       matlab.ui.control.Switch
        AxisLabelsSwitchLabel  matlab.ui.control.Label
        AxisLabelsSwitch       matlab.ui.control.Switch
        PointsPanel            matlab.ui.container.Panel
        UndoButton             matlab.ui.control.Button
        RedoButton             matlab.ui.control.Button
        DeletePointButton      matlab.ui.control.Button
        UITable                matlab.ui.control.Table
        CopyButton             matlab.ui.control.Button
        EditButton             matlab.ui.control.StateButton
        StatusWindow           matlab.ui.control.TextArea
        ImagePanel             matlab.ui.container.Panel
        PickPointsButton       matlab.ui.control.Button
        FrameDropDown          matlab.ui.control.DropDown
        FwdImageButton         matlab.ui.control.Button
        BackImageButton        matlab.ui.control.Button
    end

   
   properties (Constant)
      axesOrder = {'xy', 'xz', 'yz'}
      editComponentList = {'UITable', 'MIPSwitch', ...
         'ColormapDropDown', 'VolumeViewSwitch', 'AxisLabelsSwitch', ...
         'AxisLabelsSwitchLabel', 'AutoMaxIntensityProjectionLabel', ...
         'ColormapDropDownLabel', 'DVolumeSwitchLabel'};
      viewComponentList = {'EditButton', 'CopyButton'};
   end
   
   properties (SetObservable)
      ColorWeight = [1 1 0]
      imPath
      volImage % the volume image currently loaded and displayed in the GUI
   end
   
   properties (SetObservable)
      PointStore
   end
   
   properties (Access = private)
      statusTimer = timer
      IVV
      SliceFig
      SliceAx
      SliceSurfaces
      SlicePlots
      sphere
   end
   
   properties (Dependent, Access = private)
      P
      RecentPath
      LocalCache
      LocalCachePath
      PointStoreArray
   end
   
   properties (Dependent)
      cmap
   end
   
   methods
      
      function out = get.cmap(app)
         out = app.ColormapDropDown.Value;
      end
      
      function out = get.RecentPath(app)
         try
            path = app.LocalCache.recentPath;
         catch
            path = [];
         end
         
         if isempty(path)
            out = '.';
         else
            out = path;
         end
      end
      
      function set.RecentPath(app, newPath)
         app.LocalCache.recentPath = newPath;
      end
      
      function set.LocalCache(app, updateCache)
         save(app.LocalCachePath, '-struct', 'updateCache');
      end
      
      function out = get.LocalCache(app)
         try
            out = load(app.LocalCachePath);
         catch
            out = struct;
         end
      end
      
      function out = get.LocalCachePath(app)
         out = fileparts(mfilename('fullpath'));
         out = fullfile(out, '.local', 'cache.mat');
      end
      
      function onIVVClose(app)
         app.IVV = [];
         app.PickPointsButton.Enable = 'on';
         app.disableComponents(app.editComponentList);
         figure(app.UIFigure);
      end
      
      function addPoint(app, newPoint)
         newPoint.flag = false;
         app.PointStore = [app.PointStore, newPoint];
      end
      
      function out = get.PointStore(app)
         if isempty(app.PointStore)
            app.PointStore = struct([]);
         end
         out = app.PointStore;
      end
      
      function out = get.PointStoreArray(app)
         out = arrayfun(@(Pt) {Pt.flag, Pt.x, Pt.y, Pt.z}, ...
            app.PointStore, 'UniformOutput', false);
         out = cat(1, out{:});
      end
      
      function set.PointStoreArray(app, newArr)
         app.PointStore = struct('flag', newArr(:, 1), ...
            'x', newArr(:, 2), 'y', newArr(:, 3), 'z', newArr(:, 4));
      end
      
   end
   
   methods (Access = private)
      
      function clearPointStore(app)
         app.pointStore = {};
         app.listStatus('Points Cleared.', 'warn', 5);
      end
      
      function doForViews(app, fun, varargin)
         for i = 1:app.nViews
            getProp = @(x) app.([app.axesOrder{i}, x]);
            args = cellfun(getProp, varargin, 'UniformOutput', false);
            fun(args{:});
         end
      end
      
      function loadImage(app)
         app.volImage = im2double(utils.loadAnyImage(app.imPath));
      end
      
      function listStatus(app, msg, flag, duration)
         L = utils.Logger('VolumePointPicker', 'vpp_log.log');
         stop(app.statusTimer);
         if nargin < 2, msg = ''; end
         if nargin < 3, flag = ''; end
         if nargin < 4, duration = []; end
         
         app.StatusWindow.Value = msg;
         switch flag
            case 'good'
               c = [47 67 19] / 100;
               L.info(msg);
            case 'warn'
               c = [85 33 10] / 100;
               L.warn(msg);
            case 'error'
               c = [100 20 20] / 100;
               L.warn(msg);
            otherwise
               c = [1 1 1] * 0.85;
               L.info(msg);
         end
         app.StatusWindow.FontColor = c;
         
         if ~isempty(duration)
            app.statusTimer = timer('TimerFcn', @(~, ~) app.listStatus, ...
               'StartDelay', duration);
            start(app.statusTimer);
         end
      end
      
      function confirmAppClose(app, fcn)
         if isempty(app.volImage)
            fcn();
         else
            title = 'Confirm Close';
            msg = 'Are you sure you want to close VPP?';
            fh = app.UIFigure;
            opts = {'Yes', 'Cancel'};
            icon = 'question';
            callback = @uiconfirmCallback;
            uiconfirm(fh, msg, title, ...
               'Options', opts, ...
               'Icon', icon, ...
               'DefaultOption', 1, ...
               'CancelOption', 2, ...
               'CloseFcn', callback);
         end
         
         function uiconfirmCallback(~, event)
            if event.SelectedOptionIndex == 1 % close
               fcn();
            end
         end
      end
      
      function confirmImageOpen(app, fcn)
         if isempty(app.volImage)
            fcn();
         else
            title = 'Confirm Open';
            msg =  ['Are you sure you want to open a new image? This will close the ', ...
               'current VPP session.'];
            fh = app.UIFigure;
            opts =  {'Open Anyway', 'Cancel'};
            icon = 'warning';
            callback = @uiconfirmCallback;
            uiconfirm(fh, msg, title, ...
               'Options', opts, ...
               'Icon', icon, ...
               'DefaultOption', 1, ...
               'CancelOption', 2, ...
               'CloseFcn', callback);
         end
         
         function uiconfirmCallback(~, event)
            if event.SelectedOptionIndex == 1 % open anyway
               if ~isempty(app.IVV)
                  close(app.IVV);
               end
               app.clearPointStore;
               fcn();
            end
         end
      end
      
      function enableComponents(app, list)
         for i = 1:length(list)
            app.(list{i}).Enable = 'on';
         end
      end
      
      function disableComponents(app, list)
         for i = 1:length(list)
            app.(list{i}).Enable = 'off';
         end
      end
      
      function openIVV(app)
         app.enableComponents(app.editComponentList);
         app.listStatus('Opening Interactive Volume Viewer ...', '', 5);
         app.IVV = IVV(app);
         app.IVV.FileName = app.imPath;
         app.IVV.figure;
             app.IVV.initializeDropPins(app.PointStore);
      end
      
      function updatePointTable(app)
         app.UITable.Data = app.PointStoreArray;
      end
      
      function updateWorkspacePointStore(app)
         toBase = @(name, value) assignin('base', name, value);
         toBase('vppImagePath', app.imPath);
         points = app.PointStoreArray;
         if isempty(points)
            pointMatrix = [];
            pointFlags = [];
         else
            pointMatrix = cell2mat(points(:, [2, 3, 4]));
            pointFlags = cell2mat(points(:, 1));
         end
         toBase('vppPoints', pointMatrix);
         toBase('vppPointFlags', pointFlags);
      end
      
      function handlePropEvents(app, src, ~)
         switch src.Name
            case 'volImage'
               if isempty(app.volImage) && ~isempty(app.imPath)
                  app.imPath = [];
               else
                  app.openIVV;
               end
               
            case 'imPath'
               if isempty(app.imPath) && ~isempty(app.volImage)
                  app.volImage = [];
               else
                  app.loadImage;
               end
               
            case 'PointStore'
               app.updateWorkspacePointStore;
               app.updatePointTable;
               
         end
      end
      
   end
   
   methods (Static)
      
   end

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, imPath)
         L = utils.Logger('volumePointPicker.startupFcn');
         L.logline('v');
         L.info('Beginnging Volume Point Picker (VPP) v0.0.2');
         L.info('\tby Corban Swain [CorbanSwain@gmail.com]');
         L.info('\tMassachusetts Institute of Technology');
         L.info('\t2018');
         L.logline('-');
         L.info('This project is in active development!');
         L.info('If you experience any issues or are having trouble');
         L.info('using the software. Please submit a "GitHub Issue" here:');
         L.info('\t>>>> http://vpp.corbanswain.com <<<<');
         L.logline('-');
         L.logline('^');
         app.addlistener('volImage','PostSet',@app.handlePropEvents);
         app.addlistener('imPath','PostSet',@app.handlePropEvents);
         app.addlistener('PointStore', 'PostSet', @app.handlePropEvents);
         app.UITable.RowName = 'numbered';
         app.VolumeViewSwitch.ItemsData = [false, true];
         switch nargin
            case 2
               app.imPath = imPath;
         end
        end

        % Button pushed function: PickPointsButton
        function PickPointsButtonPushed(app, event)
         app.openIVV;
         app.PickPointsButton.Enable = 'off';
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
         app.confirmAppClose(@closeFun);
         function closeFun
            if ~isempty(app.IVV)
               app.IVV.close;
            end
            delete(app)
         end
        end

        % Menu selected function: OpenImageMenu
        function OpenImageMenuSelected(app, event)
         app.confirmImageOpen(@openFromUI);
         function openFromUI
            [file, path] = uigetfile({
               '*.tif;*.tiff;*.nii;*.mat', 'VPP-supported Image Types (.tif, .tiff, .nii, .mat)'
               '*.*', 'Any file types'}, ...
               'Select an volume image', ...
               app.RecentPath);
            if file
               app.imPath = fullfile(path, file);
               app.RecentPath = app.imPath;
            end
         end
        end

        % Menu selected function: CloseAppMenu
        function CloseAppMenuSelected(app, event)
         app.UIFigureCloseRequest(event);
        end

        % Value changed function: EditButton
        function EditButtonValueChanged(app, event)
         value = app.EditButton.Value;
         if value
            app.EditButton.BackgroundColor = [.98 0.69 0.13];
            app.EditButton.Text = 'Done';
            app.DeletePointButton.Enable = 'on';
            app.UITable.ColumnEditable = true;
         else
            app.EditButton.BackgroundColor = [1 1 1] * 0.94;
            app.EditButton.Text = 'Edit';
            app.DeletePointButton.Enable = 'off';
            app.UITable.ColumnEditable = false;
         end
        end

        % Value changed function: ColormapDropDown
        function ColormapDropDownValueChanged(app, event)
         axs = {'SliceAx', 'IVVAx'};
         for i = 1:length(axs)
            h = app.(axs{i});
            if ~isempty(h) && isvalid(h)
               colormap(h, app.cmap);
            end
         end
        end

        % Button pushed function: CopyButton
        function CopyButtonPushed(app, event)
         nPoints = size(app.pointStore, 1);
         if nPoints == 0
            app.listStatus('No points to copy.', 'warn', 5);
            return
         end
         lns = strings(1, nPoints);
         for i = 1:nPoints
            lns(i) = sprintf("%f\t%f\t%f", app.pointStore{i, 2:4});
         end
         clipboard('copy', char(sprintf(strjoin(lns, '\n'))));
         msg = sprintf('%d point coordinate coppied to the clipboard.', nPoints);
         if nPoints ~= 1
            msg = strrep(msg, 'coordinate', 'coordinates');
         end
         app.listStatus(msg, 'good', 5);
        end

        % Value changed function: VolumeViewSwitch
        function VolumeViewSwitchValueChanged(app, event)
         value = app.VolumeViewSwitch.Value;
         disp(value);
         if value
            app.openSliceFig
         else
            close(app.SliceFig)
         end
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'Volume Point Picker (VPP)';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.FileMenu = uimenu(app.UIFigure);
            app.FileMenu.Text = 'File';
            app.OpenImageMenu = uimenu(app.FileMenu);
            app.OpenImageMenu.MenuSelectedFcn = createCallbackFcn(app, @OpenImageMenuSelected, true);
            app.OpenImageMenu.Text = 'Open Image ...';
            app.OpenRecentMenu = uimenu(app.FileMenu);
            app.OpenRecentMenu.Enable = 'off';
            app.OpenRecentMenu.Text = 'Open Recent';
            app.ExportPointsMenu = uimenu(app.FileMenu);
            app.ExportPointsMenu.Enable = 'off';
            app.ExportPointsMenu.Text = 'Export Points ...';
            app.LoadSessionMenu = uimenu(app.FileMenu);
            app.LoadSessionMenu.Enable = 'off';
            app.LoadSessionMenu.Separator = 'on';
            app.LoadSessionMenu.Text = 'Load Session ...';
            app.LoadRecentSessionMenu = uimenu(app.FileMenu);
            app.LoadRecentSessionMenu.Enable = 'off';
            app.LoadRecentSessionMenu.Text = 'Load Recent Session';
            app.SaveSessionMenu = uimenu(app.FileMenu);
            app.SaveSessionMenu.Enable = 'off';
            app.SaveSessionMenu.Text = 'Save Session ...';
            app.CloseAppMenu = uimenu(app.FileMenu);
            app.CloseAppMenu.MenuSelectedFcn = createCallbackFcn(app, @CloseAppMenuSelected, true);
            app.CloseAppMenu.Separator = 'on';
            app.CloseAppMenu.Text = 'Close App';
            app.ViewSettingsPanel = uipanel(app.UIFigure);
            app.ViewSettingsPanel.Title = 'View Settings';
            app.AutoMaxIntensityProjectionLabel = uilabel(app.ViewSettingsPanel);
            app.AutoMaxIntensityProjectionLabel.Enable = 'off';
            app.AutoMaxIntensityProjectionLabel.Text = 'Auto Max Intensity Projection';
            app.MIPSwitch = uiswitch(app.ViewSettingsPanel, 'slider');
            app.MIPSwitch.Enable = 'off';
            app.MIPSwitch.Value = 'On';
            app.ColormapDropDownLabel = uilabel(app.ViewSettingsPanel);
            app.ColormapDropDownLabel.Enable = 'off';
            app.ColormapDropDownLabel.Text = 'Colormap';
            app.ColormapDropDown = uidropdown(app.ViewSettingsPanel);
            app.ColormapDropDown.Items = {'gray', 'parula', 'jet', 'hsv', 'hot', 'cool', 'spring', 'summer', 'autumn', 'winter', 'bone', 'copper', 'pink', 'lines', 'colorcube', 'prism', 'flag'};
            app.ColormapDropDown.ValueChangedFcn = createCallbackFcn(app, @ColormapDropDownValueChanged, true);
            app.ColormapDropDown.Enable = 'off';
            app.ColormapDropDown.Value = 'gray';
            app.DVolumeSwitchLabel = uilabel(app.ViewSettingsPanel);
            app.DVolumeSwitchLabel.Enable = 'off';
            app.DVolumeSwitchLabel.Text = '3D Volume';
            app.VolumeViewSwitch = uiswitch(app.ViewSettingsPanel, 'slider');
            app.VolumeViewSwitch.ValueChangedFcn = createCallbackFcn(app, @VolumeViewSwitchValueChanged, true);
            app.VolumeViewSwitch.Enable = 'off';
            app.AxisLabelsSwitchLabel = uilabel(app.ViewSettingsPanel);
            app.AxisLabelsSwitchLabel.Enable = 'off';
            app.AxisLabelsSwitchLabel.Text = 'Axis Labels';
            app.AxisLabelsSwitch = uiswitch(app.ViewSettingsPanel, 'slider');
            app.AxisLabelsSwitch.Enable = 'off';
            app.PointsPanel = uipanel(app.UIFigure);
            app.PointsPanel.Title = 'Points';
            app.UndoButton = uibutton(app.PointsPanel, 'push');
            app.UndoButton.Enable = 'off';
            app.UndoButton.Text = '';
            app.RedoButton = uibutton(app.PointsPanel, 'push');
            app.RedoButton.Enable = 'off';
            app.RedoButton.Text = '';
            app.DeletePointButton = uibutton(app.PointsPanel, 'push');
            app.DeletePointButton.Enable = 'off';
            app.DeletePointButton.Text = 'Delete ...';
            app.UITable = uitable(app.PointsPanel);
            app.UITable.ColumnName = {''; 'x'; 'y'; 'z'};
            app.UITable.RowName = {};
            app.UITable.Enable = 'off';
            app.CopyButton = uibutton(app.PointsPanel, 'push');
            app.CopyButton.ButtonPushedFcn = createCallbackFcn(app, @CopyButtonPushed, true);
            app.CopyButton.Enable = 'off';
            app.CopyButton.Text = 'Copy All';
            app.EditButton = uibutton(app.PointsPanel, 'state');
            app.EditButton.ValueChangedFcn = createCallbackFcn(app, @EditButtonValueChanged, true);
            app.EditButton.Enable = 'off';
            app.EditButton.Text = 'Edit';
            app.StatusWindow = uitextarea(app.UIFigure);
            app.StatusWindow.Editable = 'off';
            app.StatusWindow.Value = {'Click "File > Open..." to load an image.'};
            app.ImagePanel = uipanel(app.UIFigure);
            app.ImagePanel.Title = 'Image';
            app.PickPointsButton = uibutton(app.ImagePanel, 'push');
            app.PickPointsButton.ButtonPushedFcn = createCallbackFcn(app, @PickPointsButtonPushed, true);
            app.PickPointsButton.Enable = 'off';
            app.PickPointsButton.Text = 'Pick Points';
            app.FrameDropDown = uidropdown(app.ImagePanel);
            app.FrameDropDown.Items = {};
            app.FrameDropDown.Enable = 'off';
            app.FrameDropDown.Value = {};
            app.FwdImageButton = uibutton(app.ImagePanel, 'push');
            app.FwdImageButton.Enable = 'off';
            app.FwdImageButton.Text = '';
            app.BackImageButton = uibutton(app.ImagePanel, 'push');
            app.BackImageButton.Enable = 'off';
            app.BackImageButton.Text = '';
        end
    end

    methods (Access = public)

        % Construct app
        function app = VolumePointPicker(varargin)

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end